classdef MUSHRA_training_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        Image2             matlab.ui.control.Image
        text29             matlab.ui.control.Label
        text28             matlab.ui.control.Label
        text27             matlab.ui.control.Label
        text26             matlab.ui.control.Label
        text25             matlab.ui.control.Label
        text24             matlab.ui.control.Label
        text23             matlab.ui.control.Label
        text22             matlab.ui.control.Label
        pushbutton_next    matlab.ui.control.Button
        headphones_menu    matlab.ui.control.DropDown
        soundcard_menu     matlab.ui.control.DropDown
        popupmenu8         matlab.ui.control.DropDown
        popupmenu_hearing  matlab.ui.control.DropDown
        popupmenu_gender   matlab.ui.control.DropDown
        edit_age           matlab.ui.control.EditField
        edit_name          matlab.ui.control.EditField
        pushbutton_start   matlab.ui.control.Button
        popupmenu_hpcf     matlab.ui.control.DropDown
        text8              matlab.ui.control.Label
        text21             matlab.ui.control.Label
        text9              matlab.ui.control.Label
    end

    
    methods (Access = private)
      
        function populate_channels(app)
            global mushra_settings
            try
                info = getAudioDevInfo_onlyWriter; % get soundcard info
                device = app.soundcard_menu.Value; % selected device
                % Headphones
                n_output = info{device(1),2}; % number of output channels
                if rem(n_output,2) % if odd, round down
                    n_output = n_output-1;
                end
                output_list = {};
                for i=1:n_output/2
                    output_list{i} = [num2str(2*i-1),'-',num2str(2*i)];
                end
                app.headphones_menu.Items=output_list;
                app.headphones_menu.ItemsData = 1:length(output_list);
                headphone_value = mushra_settings.headphoneCh(2)/2; %
                app.headphones_menu.Value=app.headphones_menu.ItemsData(headphone_value);
                headphones_menu_Callback(app, 0);
            catch ME
                msgbox([ME.identifier ': ' ME.message])
            end
        end
        
        function populate_soundcard(app)
            global mushra_settings
            try
                info = getAudioDevInfo_onlyWriter;
                devices = info(:,1);
                app.soundcard_menu.Items=devices;
                app.soundcard_menu.ItemsData = 1:length(devices);
                soundcard_value = mushra_settings.SoundcardID;
                app.soundcard_menu.Value=app.soundcard_menu.ItemsData(soundcard_value);
                soundcard_menu_Callback(app, 0);
            catch ME
                msgbox([ME.identifier ': ' ME.message])
            end
        end
        function populate_headphones(app)
            global mushra_settings
            myPath = fileparts(mfilename('fullpath'));
            try
                
                hpcf_list = dir(fullfile(myPath,'..','HPCF','*.mat'));
                hpcfstr = [];
                for i=1:length(hpcf_list)
                    hpcfstr = [hpcfstr; {hpcf_list(i).name(1:end-4)}];
                end
                hpcfstr = [hpcfstr; 'Other'];
                app.popupmenu_hpcf.Items = hpcfstr;
                app.popupmenu_hpcf.Value = app.popupmenu_hpcf.Items{1};
                load(fullfile(myPath,'..','HPCF',[hpcfstr{1} '.mat']));
                
                mushra_settings.hpcf = hpcf;
            catch ME
                msgbox([ME.identifier ': ' ME.message])
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global  mushra_settings
            
            movegui(app.figure1,'center')
            mushra_settings.headphoneCh = [1 2];
            mushra_settings.SoundcardID = 1;
            mushra_settings.responses.userData.name = [];
            mushra_settings.responses.userData.age = [];
            mushra_settings.responses.userData.gender = 'Male';
            mushra_settings.responses.userData.hearingIssue = 'No';
            mushra_settings.responses.userData.Expertise = '3';
            
            populate_soundcard(app);
            populate_headphones(app);
      
            rgbImage = imread('successIcon.png');
            mushra_settings.myIcon = imresize(rgbImage, [64, 64]);
            
            
        end

        % Value changed function: edit_age
        function edit_age_Callback(app, event)
           global mushra_settings
            ageStr =  app.edit_age.Value;
            if isnan(str2double(ageStr))
                userPrompt = sprintf('Age must be a numerical value.');
                uialert(app.figure1,userPrompt,'Warning','Icon','warning')
                app.edit_age.Value = '';
            else
                mushra_settings.responses.userData.age = ageStr;
            end
        end

        % Value changed function: edit_name
        function edit_name_Callback(app, event)
           global mushra_settings
            nameStr = app.edit_name.Value;
            nameStr = regexprep(nameStr, ' ', '_');
            if ~isempty(regexp(nameStr, '[^-a-zA-Z_0-9]', 'once'))
                userPrompt = sprintf('Name field can contain only alphabetic, numeric, spaces, or underscore characters.');
                uialert(app.figure1,userPrompt,'Warning','Icon','warning')
                app.edit_name.Value ='';
            else
                mushra_settings.responses.userData.name = nameStr;
            end
        end

        % Value changed function: headphones_menu
        function headphones_menu_Callback(app, event)
            global mushra_settings
            value = app.headphones_menu.Value;
            mushra_settings.headphoneCh = [(value)*2-1 (value)*2];
        end

        % Value changed function: popupmenu8
        function popupmenu8_Callback(app, event)
            global mushra_settings
            mushra_settings.responses.userData.Expertise = app.popupmenu8.Value;
        end

        % Value changed function: popupmenu_gender
        function popupmenu_gender_Callback(app, event)
           global mushra_settings
            mushra_settings.responses.userData.gender = app.popupmenu_gender.Value;
        end

        % Value changed function: popupmenu_hearing
        function popupmenu_hearing_Callback(app, event)
            global mushra_settings
            mushra_settings.responses.userData.hearingIssue = app.popupmenu_hearing.Value;
        end

        % Value changed function: popupmenu_hpcf
        function popupmenu_hpcf_Callback(app, event)
           global mushra_settings;
           myPath = fileparts(mfilename('fullpath'));
            hpcf_srt = app.popupmenu_hpcf.Value;
            if strcmp(hpcf_srt,'Other')
                mushra_settings.hpcf = [];
                 mushra_settings.hpcf.hpName = 'Other';
            else
                load(fullfile(myPath,'..','HPCF',[hpcf_srt '.mat']));
            
                mushra_settings.hpcf = hpcf;
            end
        end

        % Button pushed function: pushbutton_next
        function pushbutton_next_Callback(app, event)
           app.headphones_menu.Visible='on';
            app.soundcard_menu.Visible='on';
            app.popupmenu8.Visible='on';
            app.popupmenu_hearing.Visible='on';
            app.popupmenu_gender.Visible='on';
            app.edit_name.Visible='on';
            app.edit_age.Visible='on';
            app.pushbutton_start.Visible='on';
            app.popupmenu_hpcf.Visible='on';
            app.text21.Visible='on';
            app.text22.Visible='on';
            app.text23.Visible='on';
            app.text24.Visible='on';
            app.text25.Visible='on';
            app.text26.Visible='on';
            app.text27.Visible='on';
            app.text28.Visible='on';
            app.text29.Visible='on';
            app.pushbutton_next.Visible='off';
            app.text9.Visible = 'off';
        end

        % Button pushed function: pushbutton_start
        function pushbutton_start_Callback(app, event)
            global mushra_settings
            if strcmp(mushra_settings.hpcf.hpName,'Other')
                warning('No headphone eq is applied')
            end
            initPos = app.figure1.Position(1:2);
            close(app.figure1);
            mushram('training',initPos);
        end

        % Value changed function: soundcard_menu
        function soundcard_menu_Callback(app, event)
            global mushra_settings
            value = app.soundcard_menu.Value;
            mushra_settings.SoundcardID = value(1);
            populate_channels(app);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'MUSHRA_earAlign_training';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.text9 = uilabel(app.figure1);
            app.text9.Text = {'- The following experiment is a MUltiple Stimuli with Hidden Reference and Anchor (MUSHRA) listening test.'; '- In this listening test, you will be asked to evaluate and assign grades to test signals that were processed with'; '   different spatial sound reproduction algorithms.'; '- In each screen you will listen to several test signals and compare them to a known reference signal.'; '- Rate the relative differences between the reference and the test signal on a scale from 0 to 100.'; '- The score 100 means that the signal is not differentiable from the reference. '; '    Values downwards towards 0 mean larger difference with respect to the reference.'; '- Differences can be either spatial artifacts, time varying artifacts or spectral artifacts.'; '- At least one signal should be rated 100, because a hidden reference signal is included as one of the '; '   signals to be graded.'; '- It is possible to give the same score to several signals. (including giving several 100!)'; '- Please use the scale widely. Your grades should reflect your subjective judgement of the quality level'; '  for each of the presented test signals.'; '- You may listen to the signals in any order, any number of times.'; '- Please do not move your head while listening.'; ''; '- First, a training phase will be performed'; '    o The training phase includes two parts: User interface training and signals familiarization.'; '    o The purpose of the training phase is to assist you, as an evaluator, to achieve the followings:'; '        - User Interface training: to learn how to use the test equipment and the grading scale; and'; '        - Signals familiarization: to become familiar with all the test signals and their quality level ranges. '; '    o After the training is complete, the experiment will start.'; ''; '- Adjust the volume level during the training and don''t change it during the experiment.'};
            app.text21 = uilabel(app.figure1);
            app.text21.Visible = 'off';
            app.text21.Text = '- Please fill the details below:';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'MUSHRA Listening Experiment';
            app.popupmenu_hpcf = uidropdown(app.figure1);
            app.popupmenu_hpcf.Items = {'Other'};
            app.popupmenu_hpcf.ValueChangedFcn = createCallbackFcn(app, @popupmenu_hpcf_Callback, true);
            app.popupmenu_hpcf.Visible = 'off';
            app.popupmenu_hpcf.Value = 'Other';
            app.pushbutton_start = uibutton(app.figure1, 'push');
            app.pushbutton_start.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_start_Callback, true);
            app.pushbutton_start.Visible = 'off';
            app.pushbutton_start.Text = 'Start';
            app.edit_name = uieditfield(app.figure1, 'text');
            app.edit_name.ValueChangedFcn = createCallbackFcn(app, @edit_name_Callback, true);
            app.edit_name.Visible = 'off';
            app.edit_age = uieditfield(app.figure1, 'text');
            app.edit_age.ValueChangedFcn = createCallbackFcn(app, @edit_age_Callback, true);
            app.edit_age.Visible = 'off';
            app.popupmenu_gender = uidropdown(app.figure1);
            app.popupmenu_gender.Items = {'Male', 'Female', 'Other'};
            app.popupmenu_gender.ValueChangedFcn = createCallbackFcn(app, @popupmenu_gender_Callback, true);
            app.popupmenu_gender.Visible = 'off';
            app.popupmenu_gender.Value = 'Male';
            app.popupmenu_hearing = uidropdown(app.figure1);
            app.popupmenu_hearing.Items = {'No', 'Yes'};
            app.popupmenu_hearing.ValueChangedFcn = createCallbackFcn(app, @popupmenu_hearing_Callback, true);
            app.popupmenu_hearing.Visible = 'off';
            app.popupmenu_hearing.Value = 'No';
            app.popupmenu8 = uidropdown(app.figure1);
            app.popupmenu8.Items = {'1', '2', '3', '4', '5'};
            app.popupmenu8.ValueChangedFcn = createCallbackFcn(app, @popupmenu8_Callback, true);
            app.popupmenu8.Visible = 'off';
            app.popupmenu8.Value = '3';
            app.soundcard_menu = uidropdown(app.figure1);
            app.soundcard_menu.Items = {};
            app.soundcard_menu.ValueChangedFcn = createCallbackFcn(app, @soundcard_menu_Callback, true);
            app.soundcard_menu.Visible = 'off';
            app.soundcard_menu.Value = {};
            app.headphones_menu = uidropdown(app.figure1);
            app.headphones_menu.Items = {};
            app.headphones_menu.ValueChangedFcn = createCallbackFcn(app, @headphones_menu_Callback, true);
            app.headphones_menu.Visible = 'off';
            app.headphones_menu.Value = {};
            app.pushbutton_next = uibutton(app.figure1, 'push');
            app.pushbutton_next.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_next_Callback, true);
            app.pushbutton_next.Text = 'Next';
            app.text22 = uilabel(app.figure1);
            app.text22.Visible = 'off';
            app.text22.Text = 'Name:';
            app.text23 = uilabel(app.figure1);
            app.text23.Visible = 'off';
            app.text23.Text = 'Age:';
            app.text24 = uilabel(app.figure1);
            app.text24.Visible = 'off';
            app.text24.Text = 'Gender:';
            app.text25 = uilabel(app.figure1);
            app.text25.Visible = 'off';
            app.text25.Text = 'Do you have any known hearing issues?';
            app.text26 = uilabel(app.figure1);
            app.text26.Visible = 'off';
            app.text26.Text = 'Rate your expertise in listening to spatial sound (1-no experience, 5- expert):';
            app.text27 = uilabel(app.figure1);
            app.text27.Visible = 'off';
            app.text27.Text = ' Type of headphone you are using: ';
            app.text28 = uilabel(app.figure1);
            app.text28.Visible = 'off';
            app.text28.Text = 'Sound card:';
            app.text29 = uilabel(app.figure1);
            app.text29.Visible = 'off';
            app.text29.Text = 'Headphones channels:';
            app.Image2 = uiimage(app.figure1);
            app.Image2.ImageSource = 'Meta_Platforms_Inc._logo.svg.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MUSHRA_training_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end