classdef MUSHRA_training_App_noIntro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        text29            matlab.ui.control.Label
        text28            matlab.ui.control.Label
        text27            matlab.ui.control.Label
        headphones_menu   matlab.ui.control.DropDown
        soundcard_menu    matlab.ui.control.DropDown
        pushbutton_start  matlab.ui.control.Button
        popupmenu_hpcf    matlab.ui.control.DropDown
        text8             matlab.ui.control.Label
    end

    
    methods (Access = private)
      
        function populate_channels(app)
            global mushra_settings
            try
                info = getAudioDevInfo_onlyWriter; % get soundcard info
                device = app.soundcard_menu.Value; % selected device
                % Headphones
                n_output = info{device(1),2}; % number of output channels
                if rem(n_output,2) % if odd, round down
                    n_output = n_output-1;
                end
                output_list = {};
                for i=1:n_output/2
                    output_list{i} = [num2str(2*i-1),'-',num2str(2*i)];
                end
                app.headphones_menu.Items=output_list;
                app.headphones_menu.ItemsData = 1:length(output_list);
                headphone_value = mushra_settings.headphoneCh(2)/2; %
                app.headphones_menu.Value=app.headphones_menu.ItemsData(headphone_value);
                headphones_menu_Callback(app, 0);
            catch ME
                msgbox([ME.identifier ': ' ME.message])
            end
        end
        
        function populate_soundcard(app)
            global mushra_settings
            try
                info = getAudioDevInfo_onlyWriter;
                devices = info(:,1);
                app.soundcard_menu.Items=devices;
                app.soundcard_menu.ItemsData = 1:length(devices);
                soundcard_value = mushra_settings.SoundcardID;
                app.soundcard_menu.Value=app.soundcard_menu.ItemsData(soundcard_value);
                soundcard_menu_Callback(app, 0);
            catch ME
                msgbox([ME.identifier ': ' ME.message])
            end
        end
        function populate_headphones(app)
            global mushra_settings
            myPath = fileparts(mfilename('fullpath'));
            try
                
                hpcf_list = dir(fullfile(myPath,'..','HPCF','*.mat'));
                hpcfstr = [];
                for i=1:length(hpcf_list)
                    hpcfstr = [hpcfstr; {hpcf_list(i).name(1:end-4)}];
                end
                hpcfstr = [hpcfstr; 'Other'];
                app.popupmenu_hpcf.Items = hpcfstr;
                app.popupmenu_hpcf.Value = app.popupmenu_hpcf.Items{1};
                load(fullfile(myPath,'..','HPCF',[hpcfstr{1} '.mat']));
                
                mushra_settings.hpcf = hpcf;
            catch ME
                msgbox([ME.identifier ': ' ME.message])
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global  mushra_settings
            
            movegui(app.figure1,'center')
            mushra_settings.headphoneCh = [1 2];
            mushra_settings.SoundcardID = 1;
            mushra_settings.responses.userData.name = [];
            mushra_settings.responses.userData.age = [];
            mushra_settings.responses.userData.gender = '';
            mushra_settings.responses.userData.hearingIssue = '';
            mushra_settings.responses.userData.Expertise = '';
            
            populate_soundcard(app);
            populate_headphones(app);
      
            rgbImage = imread('successIcon.png');
            mushra_settings.myIcon = imresize(rgbImage, [64, 64]);
            
            
        end

        % Callback function
        function edit_age_Callback(app, event)
           global mushra_settings
            ageStr =  app.edit_age.Value;
            if isnan(str2double(ageStr))
                userPrompt = sprintf('Age must be a numerical value.');
                uialert(app.figure1,userPrompt,'Warning','Icon','warning')
                app.edit_age.Value = '';
            else
                mushra_settings.responses.userData.age = ageStr;
            end
        end

        % Callback function
        function edit_name_Callback(app, event)
           global mushra_settings
            nameStr = app.edit_name.Value;
            nameStr = regexprep(nameStr, ' ', '_');
            if ~isempty(regexp(nameStr, '[^-a-zA-Z_0-9]', 'once'))
                userPrompt = sprintf('Name field can contain only alphabetic, numeric, spaces, or underscore characters.');
                uialert(app.figure1,userPrompt,'Warning','Icon','warning')
                app.edit_name.Value ='';
            else
                mushra_settings.responses.userData.name = nameStr;
            end
        end

        % Value changed function: headphones_menu
        function headphones_menu_Callback(app, event)
            global mushra_settings
            value = app.headphones_menu.Value;
            mushra_settings.headphoneCh = [(value)*2-1 (value)*2];
        end

        % Callback function
        function popupmenu8_Callback(app, event)
            global mushra_settings
            mushra_settings.responses.userData.Expertise = app.popupmenu8.Value;
        end

        % Callback function
        function popupmenu_gender_Callback(app, event)
           global mushra_settings
            mushra_settings.responses.userData.gender = app.popupmenu_gender.Value;
        end

        % Callback function
        function popupmenu_hearing_Callback(app, event)
            global mushra_settings
            mushra_settings.responses.userData.hearingIssue = app.popupmenu_hearing.Value;
        end

        % Value changed function: popupmenu_hpcf
        function popupmenu_hpcf_Callback(app, event)
           global mushra_settings;
           myPath = fileparts(mfilename('fullpath'));
            hpcf_srt = app.popupmenu_hpcf.Value;
            if strcmp(hpcf_srt,'Other')
                mushra_settings.hpcf = [];
                 mushra_settings.hpcf.hpName = 'Other';
            else
                load(fullfile(myPath,'..','HPCF',[hpcf_srt '.mat']));
            
                mushra_settings.hpcf = hpcf;
            end
        end

        % Callback function
        function pushbutton_next_Callback(app, event)
           app.headphones_menu.Visible='on';
            app.soundcard_menu.Visible='on';
            app.popupmenu8.Visible='on';
            app.popupmenu_hearing.Visible='on';
            app.popupmenu_gender.Visible='on';
            app.edit_name.Visible='on';
            app.edit_age.Visible='on';
            app.pushbutton_start.Visible='on';
            app.popupmenu_hpcf.Visible='on';
            app.text21.Visible='on';
            app.text22.Visible='on';
            app.text23.Visible='on';
            app.text24.Visible='on';
            app.text25.Visible='on';
            app.text26.Visible='on';
            app.text27.Visible='on';
            app.text28.Visible='on';
            app.text29.Visible='on';
            app.pushbutton_next.Visible='off';
            app.text9.Visible = 'off';
        end

        % Button pushed function: pushbutton_start
        function pushbutton_start_Callback(app, event)
            global mushra_settings
            if strcmp(mushra_settings.hpcf.hpName,'Other')
                warning('No headphone eq is applied')
            end
            initPos = app.figure1.Position(1:2);
            close(app.figure1);
            mushram('training',initPos);
        end

        % Value changed function: soundcard_menu
        function soundcard_menu_Callback(app, event)
            global mushra_settings
            value = app.soundcard_menu.Value;
            mushra_settings.SoundcardID = value(1);
            populate_channels(app);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MUSHRA_earAlign_training';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'MUSHRA Listening Experiment';
            app.popupmenu_hpcf = uidropdown(app.figure1);
            app.popupmenu_hpcf.Items = {'Other'};
            app.popupmenu_hpcf.ValueChangedFcn = createCallbackFcn(app, @popupmenu_hpcf_Callback, true);
            app.popupmenu_hpcf.Value = 'Other';
            app.pushbutton_start = uibutton(app.figure1, 'push');
            app.pushbutton_start.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_start_Callback, true);
            app.pushbutton_start.Text = 'Start';
            app.soundcard_menu = uidropdown(app.figure1);
            app.soundcard_menu.Items = {};
            app.soundcard_menu.ValueChangedFcn = createCallbackFcn(app, @soundcard_menu_Callback, true);
            app.soundcard_menu.Value = {};
            app.headphones_menu = uidropdown(app.figure1);
            app.headphones_menu.Items = {};
            app.headphones_menu.ValueChangedFcn = createCallbackFcn(app, @headphones_menu_Callback, true);
            app.headphones_menu.Value = {};
            app.text27 = uilabel(app.figure1);
            app.text27.Text = ' Type of headphone you are using: ';
            app.text28 = uilabel(app.figure1);
            app.text28.Text = 'Sound card:';
            app.text29 = uilabel(app.figure1);
            app.text29.Text = 'Headphones channels:';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MUSHRA_training_App_noIntro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end